classdef ImagePyramid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImagePyramidPanel        matlab.ui.container.Panel
        step1                    matlab.ui.control.StateButton
        PathLabel                matlab.ui.control.Label
        orig_img                 matlab.ui.control.UIAxes
        EdgeLevel2               matlab.ui.control.UIAxes
        superResolu1             matlab.ui.control.UIAxes
        sub1                     matlab.ui.control.UIAxes
        pyLevel2                 matlab.ui.control.UIAxes
        EdgeLevel1               matlab.ui.control.UIAxes
        superResolu2             matlab.ui.control.UIAxes
        guass1                   matlab.ui.control.UIAxes
        down1                    matlab.ui.control.UIAxes
        up_3                     matlab.ui.control.UIAxes
        guass5                   matlab.ui.control.UIAxes
        guass2                   matlab.ui.control.UIAxes
        down2                    matlab.ui.control.UIAxes
        pyLevel3                 matlab.ui.control.UIAxes
        up                       matlab.ui.control.UIAxes
        guass3                   matlab.ui.control.UIAxes
        sub2                     matlab.ui.control.UIAxes
        superResolu3             matlab.ui.control.UIAxes
        up_2                     matlab.ui.control.UIAxes
        guass4                   matlab.ui.control.UIAxes
        add2                     matlab.ui.control.UIAxes
        up_4                     matlab.ui.control.UIAxes
        guass6                   matlab.ui.control.UIAxes
        add1                     matlab.ui.control.UIAxes
        step2                    matlab.ui.control.Button
        step3                    matlab.ui.control.Button
        step4                    matlab.ui.control.Button
        step5                    matlab.ui.control.Button
        step6                    matlab.ui.control.Button
        step7                    matlab.ui.control.Button
        InstructionLabel         matlab.ui.control.Label
        LaplacianPyramidLabel    matlab.ui.control.Label
        LaplacianPyramidLabel_2  matlab.ui.control.Label
        GuassianPyramidLabel     matlab.ui.control.Label
        GuassianPyramidLabel_2   matlab.ui.control.Label
        GuassianPyramidLabel_3   matlab.ui.control.Label
        GuassianPyramidLabel_4   matlab.ui.control.Label
        GuassianPyramidLabel_5   matlab.ui.control.Label
        GuassianPyramidLabel_6   matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            imshow('blank.png','Parent',app.orig_img);
            imshow('blank.png','Parent',app.superResolu1);
            imshow('blank.png','Parent',app.EdgeLevel1);
            imshow('blank.png','Parent',app.EdgeLevel2);
            imshow('blank.png','Parent',app.pyLevel2);
            imshow('blank.png','Parent',app.superResolu2);
            imshow('blank.png','Parent',app.pyLevel3)   ;
            imshow('blank.png','Parent',app.superResolu3) ;  
            imshow('up.png','Parent',app.up);
            imshow('up.png','Parent',app.up_2);
            imshow('up.png','Parent',app.up_3);
            imshow('up.png','Parent',app.up_4);
            imshow('down.png','Parent',app.down1);
            imshow('down.png','Parent',app.down2);
            imshow('guass.png','Parent',app.guass1);
            imshow('guass.png','Parent',app.guass2);
            imshow('guass.png','Parent',app.guass3);
            imshow('guass.png','Parent',app.guass4);
            imshow('guass.png','Parent',app.guass5);
            imshow('guass.png','Parent',app.guass6);
            imshow('sub.png','Parent',app.sub1);
            imshow('sub.png','Parent',app.sub2);
            imshow('add.png','Parent',app.add1);
            imshow('add.png','Parent',app.add2);   
            app.PathLabel.Text = "Input Image";
        end

        % Value changed function: step1
        function step1ValueChanged(app, event)
            value = app.step1.Value;
            [file,path] = uigetfile({'*.png';'*.jpg'},'File Selector');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               g= imread(fullfile(path,file));   
               g = rgb2gray(g);
               imshow(g, 'parent', app.orig_img);
               app.PathLabel.Text = fullfile(path,file);   
               app.InstructionLabel.Text = "Transform Image to Gray" 
            end
%                g= imread('sub.png');
%                g = rgb2gray(g);
%                imshow(g, 'parent', app.orig_img);
% %                app.PathLabel.Text = fullfile(path,file)   
%                app.InstructionLabel.Text = "Transform Image to Gray"; 
            
        end

        % Button pushed function: step2
        function step2ButtonPushed(app, event)
            Level_1 = getimage(app.orig_img);
%             Level_1 = imgaussfilt(Level_1, 2);      
            Level_2 = impyramid(Level_1, 'reduce');            
            imshow(Level_2, 'parent', app.pyLevel2);
            app.InstructionLabel.Text = "Make impyramid Level 2" 
        end

        % Button pushed function: step3
        function step3ButtonPushed(app, event)
            Level_2 = getimage(app.pyLevel2);
%             Level_2 = imgaussfilt(Level_2, 2);      
            Level_3 = impyramid(Level_2, 'reduce');            
            imshow(Level_3, 'parent', app.pyLevel3); 
            imshow(Level_3, 'parent', app.superResolu3); 
            app.InstructionLabel.Text = "Make impyramid Level 3" 
        end

        % Button pushed function: step4
        function step4ButtonPushed(app, event)
            Level_2 = getimage(app.pyLevel2);
            up_Level_2 = impyramid(Level_2, 'expand'); 
%             up_Level_2 = imgaussfilt(up_Level_2, 2); 
            [a,b] = size(up_Level_2);

            Level_1 = getimage(app.orig_img);
            Level_1 = Level_1(1:a,1:b);          
            
            cotur_lev1 = Level_1 - up_Level_2;
            imshow(cotur_lev1, 'parent', app.EdgeLevel1); 
            app.InstructionLabel.Text = "Make impyramid contour1" 
        end

        % Button pushed function: step5
        function step5ButtonPushed(app, event)
            Level_3 = getimage(app.pyLevel3);
            up_Level_3 = impyramid(Level_3, 'expand'); 
%             up_Level_3 = imgaussfilt(up_Level_3, 2); 
            [a,b] = size(up_Level_3);

            Level_2 = getimage(app.pyLevel2);
            Level_2 = Level_2(1:a,1:b);  
            cotur_lev2 = Level_2 - up_Level_3;
            imshow(cotur_lev2, 'parent', app.EdgeLevel2); 
            app.InstructionLabel.Text = "Make impyramid contour2" 
        end

        % Button pushed function: step6
        function step6ButtonPushed(app, event)
            Level_3 = getimage(app.superResolu3);
            up_Level_3 = impyramid(Level_3, 'expand'); 
%             up_Level_3 = imgaussfilt(up_Level_3, 2); 
            [a,b] = size(up_Level_3);

            cotur_lev2 = getimage(app.EdgeLevel2);
            cotur_lev2 = cotur_lev2(1:a,1:b);  
            super_Resolu2 = cotur_lev2 + up_Level_3;
            imshow(super_Resolu2, 'parent', app.superResolu2); 
            app.InstructionLabel.Text = "Make super Resolution Level 2"
        end

        % Button pushed function: step7
        function step7ButtonPushed(app, event)
            Level_2 = getimage(app.superResolu2);
            up_Level_2 = impyramid(Level_2, 'expand'); 
%             up_Level_2 = imgaussfilt(up_Level_2, 2); 
            [a,b] = size(up_Level_2);

            cotur_lev1 = getimage(app.EdgeLevel1);
            cotur_lev1 = cotur_lev1(1:a,1:b);  
            super_Resolu1 = cotur_lev1 + up_Level_2;
            imshow(super_Resolu1, 'parent', app.superResolu1); 
            app.InstructionLabel.Text = "Make super Resolution Level 1"
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ImagePyramidPanel = uipanel(app.UIFigure);
            app.ImagePyramidPanel.Title = 'Image Pyramid';
            app.step1 = uibutton(app.ImagePyramidPanel, 'state');
            app.step1.ValueChangedFcn = createCallbackFcn(app, @step1ValueChanged, true);
            app.step1.Text = 'Input Image...';
            app.PathLabel = uilabel(app.ImagePyramidPanel);
            app.PathLabel.Text = 'Path';
            app.orig_img = uiaxes(app.ImagePyramidPanel);
            app.EdgeLevel2 = uiaxes(app.ImagePyramidPanel);
            app.superResolu1 = uiaxes(app.ImagePyramidPanel);
            app.sub1 = uiaxes(app.ImagePyramidPanel);
            app.pyLevel2 = uiaxes(app.ImagePyramidPanel);
            app.EdgeLevel1 = uiaxes(app.ImagePyramidPanel);
            app.superResolu2 = uiaxes(app.ImagePyramidPanel);
            app.guass1 = uiaxes(app.ImagePyramidPanel);
            app.down1 = uiaxes(app.ImagePyramidPanel);
            app.up_3 = uiaxes(app.ImagePyramidPanel);
            app.guass5 = uiaxes(app.ImagePyramidPanel);
            app.guass2 = uiaxes(app.ImagePyramidPanel);
            app.down2 = uiaxes(app.ImagePyramidPanel);
            app.pyLevel3 = uiaxes(app.ImagePyramidPanel);
            app.up = uiaxes(app.ImagePyramidPanel);
            app.guass3 = uiaxes(app.ImagePyramidPanel);
            app.sub2 = uiaxes(app.ImagePyramidPanel);
            app.superResolu3 = uiaxes(app.ImagePyramidPanel);
            app.up_2 = uiaxes(app.ImagePyramidPanel);
            app.guass4 = uiaxes(app.ImagePyramidPanel);
            app.add2 = uiaxes(app.ImagePyramidPanel);
            app.up_4 = uiaxes(app.ImagePyramidPanel);
            app.guass6 = uiaxes(app.ImagePyramidPanel);
            app.add1 = uiaxes(app.ImagePyramidPanel);
            app.step2 = uibutton(app.ImagePyramidPanel, 'push');
            app.step2.ButtonPushedFcn = createCallbackFcn(app, @step2ButtonPushed, true);
            app.step2.Text = 'step2';
            app.step3 = uibutton(app.ImagePyramidPanel, 'push');
            app.step3.ButtonPushedFcn = createCallbackFcn(app, @step3ButtonPushed, true);
            app.step3.Text = 'step3';
            app.step4 = uibutton(app.ImagePyramidPanel, 'push');
            app.step4.ButtonPushedFcn = createCallbackFcn(app, @step4ButtonPushed, true);
            app.step4.Text = 'step4';
            app.step5 = uibutton(app.ImagePyramidPanel, 'push');
            app.step5.ButtonPushedFcn = createCallbackFcn(app, @step5ButtonPushed, true);
            app.step5.Text = 'step5';
            app.step6 = uibutton(app.ImagePyramidPanel, 'push');
            app.step6.ButtonPushedFcn = createCallbackFcn(app, @step6ButtonPushed, true);
            app.step6.Text = 'step6';
            app.step7 = uibutton(app.ImagePyramidPanel, 'push');
            app.step7.ButtonPushedFcn = createCallbackFcn(app, @step7ButtonPushed, true);
            app.step7.Text = 'step7';
            app.InstructionLabel = uilabel(app.ImagePyramidPanel);
            app.InstructionLabel.Text = 'Instruction';
            app.LaplacianPyramidLabel = uilabel(app.ImagePyramidPanel);
            app.LaplacianPyramidLabel.Text = 'Laplacian Pyramid';
            app.LaplacianPyramidLabel_2 = uilabel(app.ImagePyramidPanel);
            app.LaplacianPyramidLabel_2.Text = 'Laplacian Pyramid';
            app.GuassianPyramidLabel = uilabel(app.ImagePyramidPanel);
            app.GuassianPyramidLabel.Text = 'Guassian Pyramid';
            app.GuassianPyramidLabel_2 = uilabel(app.ImagePyramidPanel);
            app.GuassianPyramidLabel_2.Text = 'Guassian Pyramid';
            app.GuassianPyramidLabel_3 = uilabel(app.ImagePyramidPanel);
            app.GuassianPyramidLabel_3.Text = 'Guassian Pyramid';
            app.GuassianPyramidLabel_4 = uilabel(app.ImagePyramidPanel);
            app.GuassianPyramidLabel_4.Text = 'Guassian Pyramid';
            app.GuassianPyramidLabel_5 = uilabel(app.ImagePyramidPanel);
            app.GuassianPyramidLabel_5.Text = 'Guassian Pyramid';
            app.GuassianPyramidLabel_6 = uilabel(app.ImagePyramidPanel);
            app.GuassianPyramidLabel_6.Text = 'Guassian Pyramid';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImagePyramid

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end